classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        qButton   matlab.ui.control.Button
        xButton   matlab.ui.control.Button
        dButton   matlab.ui.control.Button
        aButton   matlab.ui.control.Button
        sButton   matlab.ui.control.Button
        wButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        GestureCode = 0; % 0 = no command
 % Description
        
    GestureTimer % This is the timer object that runs in the background
 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

    % Create and start a timer to update gesture_code in the workspace
    app.GestureTimer = timer( ...
        'ExecutionMode', 'fixedRate', ... % It runs repeatedly
        'Period', 0.1, ...                % Every 0.1 seconds
        'TimerFcn', @(~,~) updateGestureCode(app));
 % Send GestureCode to workspace
     % Store gesture code as a time series in the base workspace
    function updateGestureCode(app)
        persistent t;
        persistent data;

        if isempty(t)
            t = 0;
            data = [];
        else
            t = t + 0.1; % time step
        end

        data = [data; t, app.GestureCode];
        assignin('base', 'gesture_code', data);
    end


    start(app.GestureTimer); % Start the timer

        end

        % Button pushed function: wButton
        function wButtonPushed(app, event)
            function ForwardButtonPushed(app, event)
    app.GestureCode = 1; % 1 = forward
end
        end

        % Button pushed function: sButton
        function sButtonPushed(app, event)
            function BackwardButtonPushed(app, event)
    app.GestureCode = 2; % 2 = backward
end
        end

        % Button pushed function: aButton
        function aButtonPushed(app, event)
            function LeftButtonPushed(app, event)
    app.GestureCode = 3; % 3 = left
end
        end

        % Button pushed function: dButton
        function dButtonPushed(app, event)
            function RightButtonPushed(app, event)
    app.GestureCode = 4; % 4 = right
end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            function StopButtonPushed(app, event)
    app.GestureCode = 5; % 5 = stop
end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            function CloseRequestFcn(app, event)
    % Stop and delete the timer when app closes
    if ~isempty(app.GestureTimer)
        stop(app.GestureTimer);
        delete(app.GestureTimer);
    end

    delete(app); % Close the app properly
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.wButton = uibutton(app.UIFigure, 'push');
            app.wButton.ButtonPushedFcn = createCallbackFcn(app, @wButtonPushed, true);
            app.wButton.Text = 'w';
            app.sButton = uibutton(app.UIFigure, 'push');
            app.sButton.ButtonPushedFcn = createCallbackFcn(app, @sButtonPushed, true);
            app.sButton.Text = 's';
            app.aButton = uibutton(app.UIFigure, 'push');
            app.aButton.ButtonPushedFcn = createCallbackFcn(app, @aButtonPushed, true);
            app.aButton.Text = 'a';
            app.dButton = uibutton(app.UIFigure, 'push');
            app.dButton.ButtonPushedFcn = createCallbackFcn(app, @dButtonPushed, true);
            app.dButton.Text = 'd';
            app.xButton = uibutton(app.UIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.qButton = uibutton(app.UIFigure, 'push');
            app.qButton.Text = 'q';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end